classdef Project8th < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CentralUniversityofKarnatakaElectricityBillGenerationAppLabel  matlab.ui.control.Label
        ConnectedLoadkWLabel            matlab.ui.control.Label
        ConnectedLoadkWEditField        matlab.ui.control.NumericEditField
        ElectricityDutyTaxEditField     matlab.ui.control.NumericEditField
        ElectricityDutyTaxLabel         matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        NetAmountRsEditField            matlab.ui.control.NumericEditField
        NetAmountRsEditFieldLabel       matlab.ui.control.Label
        ETaxRsEditField                 matlab.ui.control.NumericEditField
        ETaxRsEditFieldLabel            matlab.ui.control.Label
        SubsidyCostEditField            matlab.ui.control.NumericEditField
        SubsidyCostEditFieldLabel       matlab.ui.control.Label
        FixedChargesRsEditField         matlab.ui.control.NumericEditField
        FixedChargesLabel               matlab.ui.control.Label
        TotalCurrentChargesRsEditField  matlab.ui.control.NumericEditField
        TotalCurrentChargesRsEditFieldLabel  matlab.ui.control.Label
        NoofUnitsConsumedEditField      matlab.ui.control.NumericEditField
        NoofUnitsConsumedEditFieldLabel  matlab.ui.control.Label
        TypeofConsumerDropDown          matlab.ui.control.DropDown
        TypeofConsumerLabel             matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ElectricityDutyTaxEditField
        function ElectricityDutyTaxEditFieldValueChanged(app, event)
            value = app.ElectricityDutyTaxEditField.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.NoofUnitsConsumedEditField.Value%200!=0
                a200u = (app.NoofUnitsConsumedEditField.Value -200)*8;
                b200u = (200*6.75);
            else
                a200u = 0;
                b200u = (app.NoofUnitsConsumedEditField.Value)*6.75;
            end
            app.TotalCurrentChargesRsEditField.Value = a200u + b200u;
            if app.ConnectedLoadkWEditField.Value < 1
                b50k = 135;
                a50k = 0;
            elseif app.ConnectedLoadkWEditField.Value%50!=0
                b50k = (50*110);
                a50k = (app.ConnectedLoadkWEditField.Value-50)*165;
            else
                b50k = (app.ConnectedLoadkWEditField.Value)*110;
                a50k = 0;
            end
            app.FixedChargesRsEditField.Value = b50k + a50k;
            app.ETaxRsEditField.Value = app.ElectricityDutyTaxEditField.Value*app.TotalCurrentChargesRsEditField.Value*0.01;
            app.SubsidyCostEditField.Value = 0;
            app.NetAmountRsEditField.Value = app.ETaxRsEditField.Value + b50k + a50k + a200u +b200u;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NoofUnitsConsumedEditField.Value = 0;
            app.ConnectedLoadkWEditField.Value = 14420;
            app.ElectricityDutyTaxEditField.Value = 0;
            app.TotalCurrentChargesRsEditField.Value = 0;
            app.SubsidyCostEditField.Value=0;
            app.FixedChargesRsEditField.Value = 0;
            app.ETaxRsEditField.Value=0;
            app.NetAmountRsEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TypeofConsumerLabel = uilabel(app.UIFigure);
            app.TypeofConsumerLabel.Text = 'Type of Consumer';
            app.TypeofConsumerDropDown = uidropdown(app.UIFigure);
            app.TypeofConsumerDropDown.Items = {'LT 2(b)(ii)'};
            app.TypeofConsumerDropDown.Value = 'LT 2(b)(ii)';
            app.NoofUnitsConsumedEditFieldLabel = uilabel(app.UIFigure);
            app.NoofUnitsConsumedEditFieldLabel.Text = {'No of Units'; 'Consumed'};
            app.NoofUnitsConsumedEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofUnitsConsumedEditField.ValueDisplayFormat = '%.3f';
            app.TotalCurrentChargesRsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCurrentChargesRsEditFieldLabel.Text = {'Total Current'; 'Charges (Rs)'};
            app.TotalCurrentChargesRsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCurrentChargesRsEditField.ValueDisplayFormat = '%.3f';
            app.TotalCurrentChargesRsEditField.Editable = 'off';
            app.FixedChargesLabel = uilabel(app.UIFigure);
            app.FixedChargesLabel.Text = 'Fixed Charges (Rs)';
            app.FixedChargesRsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FixedChargesRsEditField.ValueDisplayFormat = '%.3f';
            app.FixedChargesRsEditField.Editable = 'off';
            app.SubsidyCostEditFieldLabel = uilabel(app.UIFigure);
            app.SubsidyCostEditFieldLabel.Text = 'Subsidy Cost';
            app.SubsidyCostEditField = uieditfield(app.UIFigure, 'numeric');
            app.SubsidyCostEditField.ValueDisplayFormat = '%.3f';
            app.SubsidyCostEditField.Editable = 'off';
            app.ETaxRsEditFieldLabel = uilabel(app.UIFigure);
            app.ETaxRsEditFieldLabel.Text = 'E Tax (Rs)';
            app.ETaxRsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ETaxRsEditField.ValueDisplayFormat = '%.3f';
            app.ETaxRsEditField.Editable = 'off';
            app.NetAmountRsEditFieldLabel = uilabel(app.UIFigure);
            app.NetAmountRsEditFieldLabel.Text = 'Net Amount (Rs)';
            app.NetAmountRsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetAmountRsEditField.ValueDisplayFormat = '%.3f';
            app.NetAmountRsEditField.Editable = 'off';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ElectricityDutyTaxLabel = uilabel(app.UIFigure);
            app.ElectricityDutyTaxLabel.Text = {'Electricity Duty'; '/Tax  (%)'};
            app.ElectricityDutyTaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElectricityDutyTaxEditField.ValueDisplayFormat = '%.3f';
            app.ElectricityDutyTaxEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricityDutyTaxEditFieldValueChanged, true);
            app.ConnectedLoadkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConnectedLoadkWEditField.ValueDisplayFormat = '%.0f';
            app.ConnectedLoadkWEditField.Value = 1021692;
            app.ConnectedLoadkWLabel = uilabel(app.UIFigure);
            app.ConnectedLoadkWLabel.Text = {'Connected Load'; '(kW)'};
            app.CentralUniversityofKarnatakaElectricityBillGenerationAppLabel = uilabel(app.UIFigure);
            app.CentralUniversityofKarnatakaElectricityBillGenerationAppLabel.Text = {'Central University of Karnataka,'; 'Electricity Bill Generation App'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project8th

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end